classdef chatApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ChatAppUIFigure  matlab.ui.Figure
        GridLayout       matlab.ui.container.GridLayout
        Button           matlab.ui.control.Button
        EditField        matlab.ui.control.EditField
        UITable          matlab.ui.control.Table
    end

    
    properties (Access = private)
        modelName = "gpt-4o-mini";
        systemPrompt = "You are a MATLAB expert.";
        messages = messageHistory;
        chat
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            loadenv(".env")
            app.chat = openAIChat(app.systemPrompt, ModelName=app.modelName);
            s.role = "system";
            s.content = app.systemPrompt;
            app.UITable.Data = struct2table(s);
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            app.messages = addUserMessage(app.messages, app.EditField.Value);
            msgCells = app.messages.Messages;
            msgStructArray = [msgCells{end}];
            T = struct2table(msgStructArray);
            app.UITable.Data = [app.UITable.Data;T];
            % Generate response 
            [~, response] = generate(app.chat, app.messages);
            app.messages = addResponseMessage(app.messages, response);
            msgCells = app.messages.Messages;
            msgStructArray = [msgCells{end}];
            T = struct2table(msgStructArray);
            app.UITable.Data = [app.UITable.Data;T];
            % Clear the input
            app.EditField.Value = "";
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ChatAppUIFigure = uifigure('Visible', 'off');
            app.ChatAppUIFigure.Name = 'Chat App';
            app.GridLayout = uigridlayout(app.ChatAppUIFigure);
            app.UITable = uitable(app.GridLayout);
            app.UITable.ColumnName = {'role'; 'content'};
            app.UITable.RowName = {};
            app.EditField = uieditfield(app.GridLayout, 'text');
            app.Button = uibutton(app.GridLayout, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            % Show the figure after all components are created
            app.ChatAppUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = chatApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ChatAppUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ChatAppUIFigure)
        end
    end
end